classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup5              matlab.ui.container.TabGroup
        MotionprofileTab       matlab.ui.container.Tab
        UIAxesP                matlab.ui.control.UIAxes
        UIAxesV                matlab.ui.control.UIAxes
        UIAxesA                matlab.ui.control.UIAxes
        ModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel      matlab.ui.control.Label
        OpacitySlider          matlab.ui.control.Slider
        OpacitySliderLabel     matlab.ui.control.Label
        Theta4Slider           matlab.ui.control.Slider
        Theta4SliderLabel      matlab.ui.control.Label
        Theta3Slider           matlab.ui.control.Slider
        Theta3SliderLabel      matlab.ui.control.Label
        Theta2Slider           matlab.ui.control.Slider
        Theta2SliderLabel      matlab.ui.control.Label
        Theta1Slider           matlab.ui.control.Slider
        Theta1SliderLabel      matlab.ui.control.Label
        DrawWorkSpaceButton    matlab.ui.control.Button
        TabGroup               matlab.ui.container.TabGroup
        EndEffectorTab         matlab.ui.container.Tab
        YawEditField           matlab.ui.control.EditField
        YawEditFieldLabel      matlab.ui.control.Label
        PitchEditField         matlab.ui.control.EditField
        PitchEditFieldLabel    matlab.ui.control.Label
        RollEditField          matlab.ui.control.EditField
        RollEditFieldLabel     matlab.ui.control.Label
        PzEditField            matlab.ui.control.EditField
        PzEditFieldLabel       matlab.ui.control.Label
        PyEditField            matlab.ui.control.EditField
        PyEditFieldLabel       matlab.ui.control.Label
        PxEditField            matlab.ui.control.EditField
        PxEditFieldLabel       matlab.ui.control.Label
        ShowAxisCheckBox       matlab.ui.control.CheckBox
        EditField_4            matlab.ui.control.EditField
        EditField_3            matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        EditField              matlab.ui.control.EditField
        TabGroup2              matlab.ui.container.TabGroup
        inversekinematicsTab   matlab.ui.container.Tab
        GotoButton             matlab.ui.control.Button
        pitchEditField         matlab.ui.control.NumericEditField
        pitchEditFieldLabel    matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        PathPlanningTab        matlab.ui.container.Tab
        CPanel                 matlab.ui.container.Panel
        pitchCEditField        matlab.ui.control.NumericEditField
        pitchEditField_4Label  matlab.ui.control.Label
        ZCEditField            matlab.ui.control.NumericEditField
        ZEditField_4Label      matlab.ui.control.Label
        YCEditField            matlab.ui.control.NumericEditField
        YEditField_4Label      matlab.ui.control.Label
        XCEditField            matlab.ui.control.NumericEditField
        XEditField_4Label      matlab.ui.control.Label
        BPanel                 matlab.ui.container.Panel
        pitchBEditField        matlab.ui.control.NumericEditField
        pitchEditField_3Label  matlab.ui.control.Label
        ZBEditField            matlab.ui.control.NumericEditField
        ZEditField_3Label      matlab.ui.control.Label
        YBEditField            matlab.ui.control.NumericEditField
        YEditField_3Label      matlab.ui.control.Label
        XBEditField            matlab.ui.control.NumericEditField
        XEditField_3Label      matlab.ui.control.Label
        APanel                 matlab.ui.container.Panel
        pitchAEditField        matlab.ui.control.NumericEditField
        pitchEditField_2Label  matlab.ui.control.Label
        ZAEditField            matlab.ui.control.NumericEditField
        ZEditField_2Label      matlab.ui.control.Label
        YAEditField            matlab.ui.control.NumericEditField
        YEditField_2Label      matlab.ui.control.Label
        XAEditField            matlab.ui.control.NumericEditField
        XEditField_2Label      matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        ModeDropDown_2Label    matlab.ui.control.Label
        ModeDropDown_2         matlab.ui.control.DropDown
        a_maxEditField         matlab.ui.control.NumericEditField
        a_maxEditFieldLabel    matlab.ui.control.Label
        V_maxEditField         matlab.ui.control.NumericEditField
        V_maxEditFieldLabel    matlab.ui.control.Label
        TabGroup3              matlab.ui.container.TabGroup
        Theta1Tab              matlab.ui.container.Tab
        UIAxesTheta1dot_2      matlab.ui.control.UIAxes
        UIAxesTheta1dot        matlab.ui.control.UIAxes
        UIAxesTheta1           matlab.ui.control.UIAxes
        TabGroup3_2            matlab.ui.container.TabGroup
        Theta2Tab              matlab.ui.container.Tab
        UIAxesTheta2dot_2      matlab.ui.control.UIAxes
        UIAxesTheta2dot        matlab.ui.control.UIAxes
        UIAxesTheta2           matlab.ui.control.UIAxes
        TabGroup3_3            matlab.ui.container.TabGroup
        Theta3Tab              matlab.ui.container.Tab
        UIAxesTheta3dot_2      matlab.ui.control.UIAxes
        UIAxesTheta3dot        matlab.ui.control.UIAxes
        UIAxesTheta3           matlab.ui.control.UIAxes
        TabGroup3_4            matlab.ui.container.TabGroup
        Theta4Tab              matlab.ui.container.Tab
        UIAxesTheta4dot_2      matlab.ui.control.UIAxes
        UIAxesTheta4dot        matlab.ui.control.UIAxes
        UIAxesTheta4           matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        theta1 double 
        theta2 double 
        theta3 double 
        theta4 double
        theta1_dot 
        theta2_dot 
        theta3_dot
        theta4_dot
        Base_0 double
        Base_1 double
        Base_2 double
        Joint1_0 double
        Joint1_1 double
        Joint2_0 double
        Joint2_1 double
        Joint3_0 double   
        Joint3_1 double
        Joint4_0 double   
        Joint4_1 double
        show_axis double
        alpha double
        Jacobian_matrix double
        Stop_animation double 
        R_EE double
    end
   
    methods (Access = public)
        
        function [Mdh] = DHmatrix(app, d,theta,a,alpha)
            Mdh=[cosd(theta) -sind(theta)*cosd(alpha) sind(theta)*sind(alpha) a*cosd(theta);
             sind(theta) cosd(theta)*cosd(alpha)  -cosd(theta)*sind(alpha) a*sind(theta);
             0,sind(alpha),cosd(alpha),d;
             0,0,0,1];
        end
        
        function [X, Y, Z] = calculateCylinder(app, p0, p1, R)

            v = p1 - p0;
            mag = norm(v);
            v = v/mag;
            not_v = [1, 0.1, 0.1232]'; 
            if isequal(v, not_v)
                not_v = [-0.1,1,0]';
            end
            n1 = cross(v, not_v);
            n1 = n1/norm(n1);
            n2 = cross(v,n1);
            t = linspace(0, mag, 100);
            theta = linspace(0,2*pi, 100);
            [t, theta] = meshgrid(t, theta);
            X = p0(1) + v(1) * t + R * sin(theta) * n1(1) + R * cos(theta) * n2(1);
            Y = p0(2) + v(2) * t + R * sin(theta) * n1(2) + R * cos(theta) * n2(2);
            Z = p0(3) + v(3) * t + R * sin(theta) * n1(3) + R * cos(theta) * n2(3);
        end 
        
        function [] = DrawLine(app, p1, p2, color)
            p = plot3(app.UIAxes, [p1(1);p2(1)], [p1(2);p2(2)],[p1(3);p2(3)]);
            p.LineWidth = 3;
            p.Color = color;
        end
        
        function [] = DrawCylinder(app, p1 , p2, R, color, alpha)
           
            [X, Y, Z] = app.calculateCylinder(p1, p2, R);
            S1 = surf(app.UIAxes, X, Y, Z,'LineStyle','none');
            S2 = fill3(app.UIAxes, X(:,1),Y(:,1),Z(:,1),'r'); 
            S3 = fill3(app.UIAxes, X(:,100),Y(:,100),Z(:,100),'r');
            S1.FaceColor = color;
            S2.FaceColor = color;
            S3.FaceColor = color;
            
            S1.FaceAlpha = alpha;  
            S2.FaceAlpha = alpha;
            S3.FaceAlpha = alpha;
        end
        
        function [joint_i] = dot(app, Dhmatrix, joint_i_1)
            temp1 = [joint_i_1;1];
            temp2 = Dhmatrix*temp1; 
            joint_i = temp2(1:3);
        end
        
        function [] = DrawRobot(app)
                
            A01 = app.DHmatrix(7.7, app.theta1, 0, 90);
            n1 = A01(1:3,1); 
            o1 = A01(1:3,2); 
            a1 = A01(1:3,3); 
            p1 = A01(1:3,4);
            
            app.Joint1_0 = A01*[app.Base_2;1];
            app.Joint1_0 = app.Joint1_0(1:3);
            app.Joint1_1 = A01*[app.Base_1;1];
            app.Joint1_1 = app.Joint1_1(1:3);
            
            delta_theta = atan(12.8/2.4)*180/pi;            
            A12 = app.DHmatrix(0, app.theta2+delta_theta, 13.0, 0); 
            A20 = A01*A12;
            
            n2 = A20(1:3,1); 
            o2 = A20(1:3,2); 
            a2 = A20(1:3,3); 
            p2 = A20(1:3,4);
            
        
            app.Joint2_0 = A20*[app.Base_2;1];
            app.Joint2_0 = app.Joint2_0(1:3);
            app.Joint2_1 = A20*[app.Base_1;1];
            app.Joint2_1 = app.Joint2_1(1:3);
            
            A23 = app.DHmatrix(0, app.theta3-delta_theta, 12.4, 0); 
            A30 = A01*A12*A23;
            
            n3 = A30(1:3,1); 
            o3 = A30(1:3,2); 
            a3 = A30(1:3,3); 
            p3 = A30(1:3,4);
            
            
            app.Joint3_0 = A30*[app.Base_2;1];  
            app.Joint3_0 = app.Joint3_0(1:3);
            app.Joint3_1 = A30*[app.Base_1;1];
            app.Joint3_1 = app.Joint3_1(1:3);
            
            A34 = app.DHmatrix(0, app.theta4, 12.6, 0); 
            A40 = A01 *A12*A23*A34; 
            
            n4 = A40(1:3,1); 
            o4 = A40(1:3,2); 
            a4 = A40(1:3,3); 
            p_ee = A40(1:3,4);
            p4 = A40(1:3,4);
            
            app.Joint4_0 = A40*[app.Base_2;1];
            app.Joint4_0 = app.Joint4_0(1:3);
            app.Joint4_1 = A40*[app.Base_1;1];
            app.Joint4_1 = app.Joint4_1(1:3);
            
            hold(app.UIAxes, 'on');


            app.DrawCylinder(app.Base_0,app.Base_1,3,[1 0 0],app.alpha); 
            
            app.DrawCylinder(app.Base_0,(app.Joint1_0+app.Joint1_1)/2,0.5,[0.9290 0.6940 0.1250],app.alpha); 
            
            app.DrawCylinder(app.Joint1_0,app.Joint1_1,1,[0 0 1],app.alpha); 
            
            app.DrawCylinder((app.Joint1_0+app.Joint1_1)/2,(app.Joint2_0+app.Joint2_1)/2,0.5,[0.9290 0.6940 0.1250],app.alpha); 
            
            app.DrawCylinder(app.Joint2_0,app.Joint2_1,1,[0 0 1],app.alpha);
            
            app.DrawCylinder((app.Joint2_0+app.Joint2_1)/2,(app.Joint3_0+app.Joint3_1)/2,0.5,[0.9290 0.6940 0.1250],app.alpha); 
            
            app.DrawCylinder(app.Joint3_0,app.Joint3_1,1,[0 0 1],app.alpha);
            
            app.DrawCylinder((app.Joint3_0+app.Joint3_1)/2,(app.Joint4_0+app.Joint4_1)/2,0.5,[0.9290 0.6940 0.1250],app.alpha); 
            
            %[X,Y,Z] = sphere;
            %S = surf(app.UIAxes,X+p_ee(1),Y+p_ee(2),Z+p_ee(3),'facecolor','#A2142F','LineStyle','none');
            app.DrawCylinder(p_ee+a4,p_ee-a4,0.4,[1 0 1],app.alpha);
            app.DrawCylinder(p_ee+a4+1.5*n4,p_ee+a4-n4,0.5,[1 0 1],app.alpha);
            app.DrawCylinder(p_ee-a4+1.5*n4,p_ee-a4-n4,0.5,[1 0 1],app.alpha);
            %S.FaceAlpha = app.alpha;
           
            app.PxEditField.Value = num2str(p_ee(1));
            app.PyEditField.Value = num2str(p_ee(2));
            app.PzEditField.Value = num2str(p_ee(3));


            R_ee = A40(1:3,1:3);
            app.R_EE = R_ee;
            Pitch_angle = atan2(-R_ee(3,1),sqrt(R_ee(3,2)^2+R_ee(3,3)^2)); 
            if Pitch_angle == pi/2
                Yaw_angle = 0; 
                Roll_angle = atan2(R_ee(1,2), R_ee(2,2));
            
            elseif Pitch_angle == -pi/2
                Yaw_angle = 0; 
                Roll_angle = -atan2(R_ee(1,2), R_ee(2,2));
            else
                Yaw_angle = atan2(R_ee(2,1)/cos(Pitch_angle), R_ee(1,1)/cos(Pitch_angle));
                Roll_angle = atan2(R_ee(3,2)/cos(Pitch_angle), R_ee(3,3)/cos(Pitch_angle));
            end
      
            p0 = [0,0,0]';
            z0 = [0,0,1]'; 
            
            z1 = A01(1:3,3);
            
            z2 = A20(1:3,3);
         
            z3 = A30(1:3,3);
          
            z4 = A40(1:3,3);
            
            J = [cross(z0,(p4-p0)), cross(z1,(p4-p1)), cross(z2,(p4-p2)),cross(z3,(p4-p3));z0,z1,z2,z3]; 
            app.Jacobian_matrix = J; 


            app.RollEditField.Value = num2str(Roll_angle*180/pi);
            app.PitchEditField.Value = num2str(Pitch_angle*180/pi);
            app.YawEditField.Value = num2str(Yaw_angle*180/pi);
            

            if app.show_axis ~= 0
                app.DrawLine([0,0,0], [2,0,0],[1 0 0]);
                app.DrawLine([0,0,0], [0,2,0],[0 1 0]);
                app.DrawLine([0,0,0], [0,0,2],[0 0 1]);


                app.DrawLine(p1, n1*2+p1,[1 0 0]);
                app.DrawLine(p1, o1*2+p1,[0 1 0]);
                app.DrawLine(p1, a1*2+p1,[0 0 1]);


                app.DrawLine(p2, n2*2+p2,[1 0 0]);
                app.DrawLine(p2, o2*2+p2,[0 1 0]);
                app.DrawLine(p2, a2*2+p2,[0 0 1]);


                app.DrawLine(p3, n3*2+p3,[1 0 0]);
                app.DrawLine(p3, o3*2+p3,[0 1 0]);
                app.DrawLine(p3, a3*2+p3,[0 0 1]);


                app.DrawLine(p4, n4*2+p4,[1 0 0]);
                app.DrawLine(p4, o4*2+p4,[0 1 0]);
                app.DrawLine(p4, a4*2+p4,[0 0 1]);
                
            end
     
        end
        
        function [theta1, theta2, theta3, theta4] = inverse_kinematics(app, px, py, pz, pitch)
            d1 = 7.7; 
            a2 = 13.0; 
            a3 = 12.4; 
            a4 = 12.6; 
            Phi = -pitch;
            delta_theta = atan(12.8/2.4)*180/pi;
            theta1 = atan2d(py, px); 
            pr = sqrt(px^2+py^2);    
            r3 =  pr;
            z3 = pz - d1; 
            
            r2 = r3 - a4*cosd(Phi);
            z2 = z3 - a4*sind(Phi);
            c3 = (r2^2 + z2^2 - a2^ 2 - a3^2)/(2*a2*a3);
            s3 = [-sqrt(1-c3^2), sqrt(1-c3^2)];
            try
                theta3_ = atan2d(s3, [c3,c3]);
                theta3_list = theta3_ + delta_theta;
            
                c2 = ((a2 + a3*cosd(theta3_))*r2 + (a3*sind(theta3_))*z2)/(r2^2+z2^2);
                s2 = ((a2 + a3*cosd(theta3_))*z2 - (a3*sind(theta3_))*r2)/(r2^2+z2^2);
                theta2_ = atan2d(s2, c2); 
                theta2_list = theta2_ - delta_theta;
                theta4_list = Phi - theta2_ - theta3_;
                
                index = []; 
                for i =1:2
                    if (theta2_list(i) + theta3_list(i) + theta3_list(i)) >= -90  || (theta2_list(i) + theta3_list(i) + theta3_list(i)) <= 90
                        index = [index, i];
                    end
                end
                if length(index) == 0
                    f = warndlg('out of work space','Warning');
                    theta1 = app.theta1; 
                    theta2 = app.theta2; 
                    theta3 = app.theta3; 
                    theta4 = app.theta4;
                    app.Stop_animation = 1;
                      
                end
                theta2_list = theta2_list(index);
                theta3_list = theta3_list(index);
                theta4_list = theta4_list(index);
                [~, index_resturn] = min(abs(theta2_list-app.theta2) + abs(theta3_list-app.theta3) + abs(theta4_list-app.theta4));
                theta2 = theta2_list(index_resturn); 
                theta3 = theta3_list(index_resturn);
                theta4 = theta4_list(index_resturn);

            catch 
                f = warndlg('out of work space','Warning');
                theta1 = app.theta1; 
                theta2 = app.theta2; 
                theta3 = app.theta3; 
                theta4 = app.theta4;
                app.Stop_animation = 1;
            end
        end
        
        function [] = Jacabian(app, path)
            
            x_dot = diff(path(1,:))/0.05;
            y_dot = diff(path(2,:))/0.05;
            z_dot = diff(path(3,:))/0.05;
            pitch_dot = diff(path(4,:))/0.05;
            temp = (app.Jacobian_matrix^-1)*[x_dot;y_dot;z_dot; pitch_dot]; 
            app.theta1_dot = temp(1,:);
            app.theta1_dot = temp(2,:);
        end
        
        function [] = path_planning_linear(app, point1, point2)
            v_max = app.V_maxEditField.Value;
            a_max = app.a_maxEditField.Value
            [Pos, Vel, Acce, time] = app.motion_profile(0, norm(point2-point1), v_max, a_max); 
            path = point1 + Pos.*(point2-point1)/norm(point2-point1);
            delta_t = time(2) - time(1);
            x_dot = diff(path(1,:))/delta_t;
            y_dot = diff(path(2,:))/delta_t;
            z_dot = diff(path(3,:))/delta_t;
                        
            x_dot = [x_dot,0];
            y_dot = [y_dot,0];
            z_dot = [z_dot,0];
                        
            T = (length(x_dot))*0.01; 
            
            [~, point_num] =  size(path);
                
            theta1_list = [];
            theta1dot_list = [];
            
            theta2_list = [];
            theta2dot_list = [];
            
            theta3_list = [];
            theta3dot_list = [];
            
            theta4_list = [];
            theta4dot_list = [];
            theta1_dot2 = [];
            theta2_dot2 = [];
            theta3_dot2 = [];
            theta4_dot2 = [];
            R_ee_Pre = zeros(3,3);
            for i  = 1:point_num
                cla(app.UIAxes);

                [theta1, theta2, theta3, theta4] = app.inverse_kinematics(path(1, i), path(2, i), path(3, i), path(4, i));
                app.theta1 = theta1; 
                app.theta2 = theta2; 
                app.theta3 = theta3; 
                app.theta4 = theta4; 
                

                app.DrawRobot(); 
                
                
                p = plot3(app.UIAxes, path(1, 1:i), path(2, 1:i), path(3, 1:i),'Color','red'); 
                p.LineWidth = 3;
                scatter3(app.UIAxes, point1(1), point1(2), point1(3),'MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
                scatter3(app.UIAxes, point2(1), point2(2), point2(3),'MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);

                theta1_list = [theta1_list, theta1];
                p = plot(app.UIAxesTheta1,time(1:i), theta1_list,'Color','blue'); 
                p.LineWidth =2;
                 if (i>1)
                    theta1_dot2 = diff(theta1_list)/delta_t;
                    p = plot(app.UIAxesTheta1dot_2,time(1:i-1), theta1_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                theta2_list = [theta2_list, theta2];
                if (i>1)
                    theta2_dot2 = diff(theta2_list)/delta_t;
                    p = plot(app.UIAxesTheta2dot_2,time(1:i-1), theta2_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                
                p = plot(app.UIAxesTheta2,time(1:i), theta2_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta3_list = [theta3_list, theta3];
                if (i>1)
                    theta3_dot2 = diff(theta3_list)/delta_t;
                    p = plot(app.UIAxesTheta3dot_2,time(1:i-1), theta3_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta3,time(1:i), theta3_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta4_list = [theta4_list, theta4];
                if (i>1)
                    theta4_dot2 = diff(theta4_list)/delta_t;
                    p = plot(app.UIAxesTheta4dot_2,time(1:i-1), theta4_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta4,time(1:i), theta4_list,'Color','blue'); 
                p.LineWidth =2;
                R_dot = (app.R_EE - R_ee_Pre)/delta_t; 
                S = R_dot*app.R_EE';
                wx = S(3,2);
                wy = S(1,3);
                wz = S(2,1); 
               
                temp = pinv(app.Jacobian_matrix)*[x_dot(i);y_dot(i);z_dot(i);wx;wy;wz]; 

                
                theta1dot = temp(1);
                theta1dot_list = [theta1dot_list, theta1dot*180/pi];
                p = plot(app.UIAxesTheta1dot,time(1:i), theta1dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta2dot = temp(2);
                theta2dot_list = [theta2dot_list, theta2dot*180/pi];
                p = plot(app.UIAxesTheta2dot,time(1:i), theta2dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta3dot = temp(3);
                theta3dot_list = [theta3dot_list, theta3dot*180/pi];
                p = plot(app.UIAxesTheta3dot,time(1:i), theta3dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta4dot = temp(4);
                theta4dot_list = [theta4dot_list, theta4dot*180/pi];
                p = plot(app.UIAxesTheta4dot,time(1:i), theta4dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                p = plot(app.UIAxesP,time(1:i) ,Pos(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesV,time(1:i)  ,Vel(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesA,time(1:i) , Acce(1:i), 'Color','blue');
                p.LineWidth =2;
                app.EditField.Value = num2str(app.theta1);
                app.EditField_2.Value = num2str(app.theta2);
                app.EditField_3.Value = num2str(app.theta3);
                app.EditField_4.Value = num2str(app.theta4);
                pause(5/1000);
                R_ee_Pre = app.R_EE;
                if app.Stop_animation == 1
                    break;
                end
            end
        end
        
        function [] = path_planning_circular(app,P0, P1, P2)
            
            v_max = app.V_maxEditField.Value;
            a_max = app.a_maxEditField.Value; 
            
            
            P01 = P1-P0; 
            P02 = P2-P0;
            X = P01/norm(P01);
            Z = cross(P01, P02)/norm(cross(P01, P02));
            Y = cross(Z,X);
            T = [X, Y, Z, P0;0,0,0,1];
            P1_ = (T^-1)*[P1;1];
            P1_ = P1_(1:3);
            
            P2_ = (T^-1)*[P2;1];
            P2_ = P2_(1:3);
            C_ = [P1_(1)/2;((P2_(1) - P1_(1)/2)^2 + P2_(2)^2 -(P1_(1)/2)^2)/(2*P2_(2));0];
            C = T*[C_;1];
            C = C(1:3); 
            R = norm(C_);
            
            p_init = atand(C_(2)/C_(1)); 
            p_max = acosd(-(P2_ - C_)'*C_/R^2) ;
            [Pos, Vel, ACCE,time] = app.motion_profile(p_init,p_max , v_max, a_max);
            L = length(Pos);
            path = zeros(3, L);
            for i=1:L
                P_ = R*[-cosd(Pos(i));-sind(Pos(i));0] + C_; 
                P = T*[P_;1]; 
                P = P(1:3);
                path(1,i) = P(1);
                path(2,i) = P(2);
                path(3,i) = P(3);
            end
             
            delta_t = time(2) - time(1);
            x_dot = diff(path(1,:))/delta_t;
            y_dot = diff(path(2,:))/delta_t;
            z_dot = diff(path(3,:))/delta_t;

            x_dot = [0,x_dot];
            y_dot = [0,y_dot,];
            z_dot = [0,z_dot,];
            
          
            pitch_dot = zeros(size(x_dot));
            [~, point_num] =  size(path);

            theta1_list = [];
            theta1dot_list = [];
            
            theta2_list = [];
            theta2dot_list = [];
            
            theta3_list = [];
            theta3dot_list = [];
            
            theta4_list = [];
            theta4dot_list = [];
            R_ee_Pre = zeros(3,3);
            for i  = 1:point_num
                cla(app.UIAxes); 

                [theta1, theta2, theta3, theta4] = app.inverse_kinematics(path(1, i), path(2, i), path(3, i), app.pitchAEditField.Value);
                app.theta1 = theta1; 
                app.theta2 = theta2; 
                app.theta3 = theta3; 
                app.theta4 = theta4; 
                

                app.DrawRobot(); 
                p = plot3(app.UIAxes, path(1, 1:i), path(2, 1:i), path(3, 1:i),'Color','red'); 
                p.LineWidth = 3;
                scatter3(app.UIAxes, P0(1), P0(2), P0(3),'MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
                scatter3(app.UIAxes, P1(1), P1(2), P1(3),'MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);
                scatter3(app.UIAxes, P2(1), P2(2), P2(3),'MarkerEdgeColor','k','MarkerFaceColor',[0 .75 .75]);

                theta1_list = [theta1_list, theta1];
                p = plot(app.UIAxesTheta1,time(1:i), theta1_list,'Color','blue'); 
                p.LineWidth =2;
                
                    
                if (i>1)
                    theta1_dot2 = diff(theta1_list)/delta_t;
                    p = plot(app.UIAxesTheta1dot_2,time(1:i-1), theta1_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                theta2_list = [theta2_list, theta2];
                if (i>1)
                    theta2_dot2 = diff(theta2_list)/delta_t;
                    p = plot(app.UIAxesTheta2dot_2,time(1:i-1), theta2_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                
                p = plot(app.UIAxesTheta2,time(1:i), theta2_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta3_list = [theta3_list, theta3];
                if (i>1)
                    theta3_dot2 = diff(theta3_list)/delta_t;
                    p = plot(app.UIAxesTheta3dot_2,time(1:i-1), theta3_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta3,time(1:i), theta3_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta4_list = [theta4_list, theta4];
                if (i>1)
                    theta4_dot2 = diff(theta4_list)/delta_t;
                    p = plot(app.UIAxesTheta4dot_2,time(1:i-1), theta4_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta4,time(1:i), theta4_list,'Color','blue'); 
                p.LineWidth =2;
                R_dot = (app.R_EE - R_ee_Pre)/delta_t; 
                S = R_dot*app.R_EE';
                wx = S(3,2);
                wy = S(1,3);
                wz = S(2,1); 
                temp = pinv(app.Jacobian_matrix)*[x_dot(i);y_dot(i);z_dot(i);wx;wy;wz]; 

                
                theta1dot = temp(1);
                theta1dot_list = [theta1dot_list, theta1dot*180/pi];
                p = plot(app.UIAxesTheta1dot,time(1:i), theta1dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta2dot = temp(2);
                theta2dot_list = [theta2dot_list, theta2dot*180/pi];
                p = plot(app.UIAxesTheta2dot,time(1:i), theta2dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta3dot = temp(3);
                theta3dot_list = [theta3dot_list, theta3dot*180/pi];
                p = plot(app.UIAxesTheta3dot,time(1:i), theta3dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta4dot = temp(4);
                theta4dot_list = [theta4dot_list, theta4dot*180/pi];
                p = plot(app.UIAxesTheta4dot,time(1:i), theta4dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                p = plot(app.UIAxesP, Pos(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesV, Vel(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesA, ACCE(1:i), 'Color','blue');
                p.LineWidth =2;
                app.EditField.Value = num2str(app.theta1);
                app.EditField_2.Value = num2str(app.theta2);
                app.EditField_3.Value = num2str(app.theta3);
                app.EditField_4.Value = num2str(app.theta4);
                pause(1/3000);
                if app.Stop_animation == 1
                    break;
                end
                R_ee_Pre = app.R_EE;
            end

            
        end
        
        function [] = SINGULARITIES_mode(app)
            v_max = app.V_maxEditField.Value;
            a_max = app.a_maxEditField.Value; 
          
            
            P0 = [24 ;2 ;30.70332]; 
            P1 = [26.06 ;0 ;30.4595]; 
            P2 = [24 ;-2; 30.7033];
            
            P01 = P1-P0; 
            P02 = P2-P0;
            X = P01/norm(P01);
            Z = cross(P01, P02)/norm(cross(P01, P02));
            Y = cross(Z,X);
            T = [X, Y, Z, P0;0,0,0,1];
            P1_ = (T^-1)*[P1;1];
            P1_ = P1_(1:3);
            
            P2_ = (T^-1)*[P2;1];
            P2_ = P2_(1:3);
            C_ = [P1_(1)/2;((P2_(1) - P1_(1)/2)^2 + P2_(2)^2 -(P1_(1)/2)^2)/(2*P2_(2));0];
            C = T*[C_;1];
            C = C(1:3); 
            R = norm(C_);
            
            p_init = atand(C_(2)/C_(1)); 
            p_max = acosd(-(P2_ - C_)'*C_/R^2) ;
            [Pos, Vel, ACCE, time] = app.motion_profile(p_init,p_max , v_max, a_max);
            L = length(Pos);
            path = zeros(3, L);
            for i=1:L
                P_ = R*[-cosd(Pos(i));-sind(Pos(i));0] + C_; 
                P = T*[P_;1]; 
                P = P(1:3);
                path(1,i) = P(1);
                path(2,i) = P(2);
                path(3,i) = P(3);
            end
            
            delta_t = time(2) - time(1);
            x_dot = diff(path(1,:))/delta_t;
            y_dot = diff(path(2,:))/delta_t;
            z_dot = diff(path(3,:))/delta_t;

            x_dot = [x_dot,0];
            y_dot = [y_dot,0];
            z_dot = [z_dot,0];
            
            T = (length(x_dot))*0.01; 
           
            [~, point_num] =  size(path);

            theta1_list = [];
            theta1dot_list = [];
            
            theta2_list = [];
            theta2dot_list = [];
            
            theta3_list = [];
            theta3dot_list = [];
            
            theta4_list = [];
            theta4dot_list = [];
            
            theta1_dot2 = [];
            theta2_dot2 = [];
            theta3_dot2 = [];
            theta4_dot2 = [];
            R_ee_Pre = zeros(3,3);
            for i  = 1:L
                cla(app.UIAxes);

                [theta1, theta2, theta3, theta4] = app.inverse_kinematics(path(1, i), path(2, i), path(3, i),-5.7341);
                app.theta1 = theta1;
               
                app.theta2 = theta2;
%                 theta3 = Pos(i);
                app.theta3 = theta3; 
                app.theta4 = theta4; 
                
    
                app.DrawRobot(); 
                p = plot3(app.UIAxes, path(1, 1:i), path(2, 1:i), path(3, 1:i),'Color','red'); 
                p.LineWidth = 3;
           
                theta1_list = [theta1_list, theta1];
                p = plot(app.UIAxesTheta1,time(1:i), theta1_list,'Color','blue'); 
                p.LineWidth =2;
                
                if (i>1)
                    theta1_dot2 = diff(theta1_list)/delta_t;
                    p = plot(app.UIAxesTheta1dot_2,time(1:i-1), theta1_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                theta2_list = [theta2_list, theta2];
                if (i>1)
                    theta2_dot2 = diff(theta2_list)/delta_t;
                    p = plot(app.UIAxesTheta2dot_2,time(1:i-1), theta2_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                
                
                p = plot(app.UIAxesTheta2,time(1:i), theta2_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta3_list = [theta3_list, theta3];
                if (i>1)
                    theta3_dot2 = diff(theta3_list)/delta_t;
                    p = plot(app.UIAxesTheta3dot_2,time(1:i-1), theta3_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta3,time(1:i), theta3_list,'Color','blue'); 
                p.LineWidth =2;
                
                theta4_list = [theta4_list, theta4];
                if (i>1)
                    theta4_dot2 = diff(theta4_list)/delta_t;
                    p = plot(app.UIAxesTheta4dot_2,time(1:i-1), theta4_dot2,'Color','blue'); 
                    p.LineWidth =2;
                end
                p = plot(app.UIAxesTheta4,time(1:i), theta4_list,'Color','blue'); 
                p.LineWidth =2;
                R_dot = (app.R_EE - R_ee_Pre)/delta_t; 
                S = R_dot*app.R_EE';
                wx = S(3,2);
                wy = S(1,3);
                wz = S(2,1); 
               
                temp = pinv(app.Jacobian_matrix)*[x_dot(i);y_dot(i);z_dot(i);wx;wy;wz]; 

                
                theta1dot = temp(1);
                theta1dot_list = [theta1dot_list, theta1dot*180/pi];
                p = plot(app.UIAxesTheta1dot,time(1:i), theta1dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta2dot = temp(2);
                theta2dot_list = [theta2dot_list, theta2dot*180/pi];
                p = plot(app.UIAxesTheta2dot,time(1:i), theta2dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta3dot = temp(3);
                theta3dot_list = [theta3dot_list, theta3dot*180/pi];
                p = plot(app.UIAxesTheta3dot,time(1:i), theta3dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                theta4dot = temp(4);
                theta4dot_list = [theta4dot_list, theta4dot*180/pi];
                p = plot(app.UIAxesTheta4dot,time(1:i), theta4dot_list,'Color','red'); 
                p.LineWidth =2; 
                
                p = plot(app.UIAxesP, Pos(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesV, Vel(1:i), 'Color','blue');
                p.LineWidth =2;
                
                p = plot(app.UIAxesA, ACCE(1:i), 'Color','blue');
                p.LineWidth =2;
                app.EditField.Value = num2str(app.theta1);
                app.EditField_2.Value = num2str(app.theta2);
                app.EditField_3.Value = num2str(app.theta3);
                app.EditField_4.Value = num2str(app.theta4);
                pause(1/3000);
                
                
                R_ee_Pre = app.R_EE;
                
            end

        end
        
        function [p, v, a, t] = motion_profile(app,p_init, p_max, v_max, a_max)
            if v_max > sqrt(a_max*p_max)
                f = warndlg("v_max > " + num2str(sqrt(a_max*p_max)),'Warning');
           
                app.Stop_animation = 1;

            end
            t1 = v_max/a_max; 
            T = p_max/v_max + t1;
            t2 = T - t1; 
            t = linspace(0,T,100); 
            p = (1/2*a_max*t.^2 + p_init).*(t < t1) + (v_max*(t-t1) + 1/2*a_max*t1^2+p_init).*(t>=t1 & t<t2) + (v_max*(t2-t1) + 1/2*a_max*t1^2 - 1/2*a_max*(t-t2).^2 + v_max*(t-t2)+p_init).*(t>=t2); 
            v = (a_max*t).*(t<t1) + v_max.*(t>=t1 & t<t2) + (-a_max*(t-t2)+v_max).*(t>=t2); 
            a = a_max.*(t<t1) + 0.*(t>=t1 & t<t2) + (-a_max).*(t>=t2); 
        end                     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.theta1 = 0;
            app.theta2 = 0; 
            app.theta3 = 0; 
            app.theta4 = 0;
            app.Base_0 = [0,0,0]';
            app.Base_1 = [0,0,1]'; 
            app.Base_2 = [0,0,-1]';
            app.alpha = 1;
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.XLim = [-5,30];
            app.UIAxes.YLim = [-10,20]; 
            app.UIAxes.ZLim = [-3,35];
            hold(app.UIAxes, 'on');
            app.UIAxes.XLim = [-5,35];
            app.UIAxes.YLim = [-5,35];
            app.UIAxes.ZLim = [-5,35];
            delta_theta = atan(12.8/2.4)*180/pi;
            app.Theta2Slider.Limits = [-delta_theta, 180-delta_theta];
            app.Theta3Slider.Limits = [-90 + delta_theta, 90+delta_theta];
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.ModeDropDown.Value = 'forward kinematics';
            app.GotoButton.Enable = false;
            app.StartButton.Enable = false;
            app.Stop_animation = 0;
            app.DrawRobot()
            
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            cla(app.UIAxes);
            app.theta1 = changingValue;
            app.EditField.Value = num2str(app.theta1); 
            app.DrawRobot();
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            cla(app.UIAxes);
            app.theta2 = changingValue;
            app.EditField_2.Value = num2str(app.theta2); 
            app.DrawRobot();
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            cla(app.UIAxes);
            app.theta3 = changingValue;
            app.EditField_3.Value = num2str(app.theta3); 
            app.DrawRobot();
        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            cla(app.UIAxes);
            app.theta4 = changingValue;
            app.EditField_4.Value = num2str(app.theta4); 
            app.DrawRobot();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            cla(app.UIAxes);
            app.theta1 = str2double(value);
            app.Theta1Slider.Value = str2double(value);
            app.DrawRobot();
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            cla(app.UIAxes);
            app.theta2 = str2double(value);
            app.Theta2Slider.Value = str2double(value);
            app.DrawRobot();
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            cla(app.UIAxes);
            app.theta3 = str2double(value);
            app.Theta3Slider.Value = str2double(value);
            app.DrawRobot();
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            cla(app.UIAxes);
            app.theta4 = str2double(value);
            app.Theta4Slider.Value = str2double(value);
            app.DrawRobot();
        end

        % Value changed function: ShowAxisCheckBox
        function ShowAxisCheckBoxValueChanged(app, event)
            value = app.ShowAxisCheckBox.Value;
            app.show_axis = value;
            app.DrawRobot();
        end

        % Value changing function: OpacitySlider
        function OpacitySliderValueChanging(app, event)
            app.alpha = event.Value;
            cla(app.UIAxes);
            app.DrawRobot();
        end

        % Button pushed function: DrawWorkSpaceButton
        function DrawWorkSpaceButtonPushed(app, event)
            delta_theta = atan(12.8/2.4)*180/pi;
            theta1 = linspace(-180, 180, 30);
            theta2 = linspace(-delta_theta, 180-delta_theta, 10);
            theta3 = linspace(-90+delta_theta, 90+delta_theta, 10); 
            theta4 = linspace(-90, 90, 10);
            
            work_space = [];
            for i=1:30
                for j = 1:10
                    for k = 1:10
                        for h = 1:10
                             if (theta2(j) + theta3(k) + theta4(h) >= -90) || (theta2(j) + theta3(k) + theta4(h) <= 90)
                                 A10 = app.DHmatrix(7.7, theta1(i), 0, 90);
                                 A12 = app.DHmatrix(0, theta2(j)+delta_theta, 13.0, 0);
                                 A23 = app.DHmatrix(0, theta3(k)-delta_theta, 12.4, 0); 
                                 A34 = app.DHmatrix(0, theta4(h), 12.6, 0); 
                                 A40 = A10*A12*A23*A34;
                                 p_ee = A40(1:3,4)';
                                 work_space = [work_space;p_ee];
                             end
                        end
                    end
                
                end
                
            end
            scatter3(app.UIAxes, work_space(:,1), work_space(:,2), work_space(:,3));
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,'forward kinematics')
                app.GotoButton.Enable = false; 
                app.Theta1Slider.Enable = true; 
                app.Theta2Slider.Enable = true;
                app.Theta3Slider.Enable = true; 
                app.Theta4Slider.Enable = true; 
                app.EditField.Editable = true; 
                app.EditField_2.Editable = true; 
                app.EditField_3.Editable = true; 
                app.EditField_4.Editable = true; 
                app.StartButton.Enable = false;
            elseif strcmp(value,'inverse kinematics')
                app.GotoButton.Enable = true;
                app.Theta1Slider.Enable = false; 
                app.Theta2Slider.Enable = false;
                app.Theta3Slider.Enable = false; 
                app.Theta4Slider.Enable = false; 
                app.EditField.Editable = false; 
                app.EditField_2.Editable = false; 
                app.EditField_3.Editable = false; 
                app.EditField_4.Editable = false; 
                app.StartButton.Enable = false;

            elseif strcmp(value,'Path Planing')
                app.GotoButton.Enable = false;
                app.Theta1Slider.Enable = false; 
                app.Theta2Slider.Enable = false;
                app.Theta3Slider.Enable = false; 
                app.Theta4Slider.Enable = false; 
                app.EditField.Editable = false; 
                app.EditField_2.Editable = false; 
                app.EditField_3.Editable = false; 
                app.EditField_4.Editable = false; 
                app.StartButton.Enable = true;

            end
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            value = app.Theta1Slider.Value;
            
        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            px = app.XEditField.Value; 
            py = app.YEditField.Value; 
            pz = app.ZEditField.Value; 
            pitch = app.pitchEditField.Value; 
            [j1, j2, j3, j4] = app.inverse_kinematics(px, py, pz, pitch); 
           
            theta1_list = linspace(app.theta1, j1, 50); 
            theta2_list = linspace(app.theta2, j2, 50); 
            theta3_list = linspace(app.theta3, j3, 50);
            theta4_list = linspace(app.theta4, j4, 50);
            
            for i = 1:50
                    app.theta1 = theta1_list(i);
                    app.theta2 = theta2_list(i);
                    app.theta3 = theta3_list(i);
                    app.theta4 = theta4_list(i);
                    app.EditField.Value = num2str(app.theta1);
                    app.EditField_2.Value = num2str(app.theta2);
                    app.EditField_3.Value = num2str(app.theta3);
                    app.EditField_4.Value = num2str(app.theta4);
                    cla(app.UIAxes);
                    app.DrawRobot(); 
                    pause(0.005);
            end

            
        end

        % Value changed function: OpacitySlider
        function OpacitySliderValueChanged(app, event)
            value = app.OpacitySlider.Value;
            app.DrawRobot();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            value = app.ModeDropDown_2.Value;
            app.Stop_animation = 0;
            if strcmp(value,'Linear')
                app.path_planning_linear([app.XAEditField.Value, app.YAEditField.Value, app.ZAEditField.Value, app.pitchAEditField.Value]', ...
                    [app.XBEditField.Value, app.YBEditField.Value, app.ZBEditField.Value, app.pitchBEditField.Value]');
            elseif strcmp(value,'Circular')
                app.path_planning_circular([app.XAEditField.Value, app.YAEditField.Value, app.ZAEditField.Value]', ...
                    [app.XBEditField.Value, app.YBEditField.Value, app.ZBEditField.Value]', ...
                    [app.XCEditField.Value, app.YCEditField.Value, app.ZCEditField.Value]');
            elseif strcmp(value,'SINGULARITIES')
                app.SINGULARITIES_mode();

            end
            
        end

        % Drop down opening function: ModeDropDown_2
        function ModeDropDown_2Opening(app, event)
            value = app.ModeDropDown.Value;
            
        end

        % Value changed function: ModeDropDown_2
        function ModeDropDown_2ValueChanged(app, event)
            value = app.ModeDropDown_2.Value;
            app.Stop_animation = 0;
            if strcmp(value,'Linear')
                app.CPanel.Enable = 'off'; 
            elseif strcmp(value,'Circular')
                app.CPanel.Enable = 'on'; 
                        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot')
            xlabel(app.UIAxes, 'X(cm)')
            ylabel(app.UIAxes, 'Y(cm)')
            zlabel(app.UIAxes, 'Z(cm)')
            app.TabGroup3_4 = uitabgroup(app.UIFigure);
            app.Theta4Tab = uitab(app.TabGroup3_4);
            app.Theta4Tab.Title = 'Theta4';
            app.UIAxesTheta4 = uiaxes(app.Theta4Tab);
            title(app.UIAxesTheta4, 'theta4')
            zlabel(app.UIAxesTheta4, 'Z')
            app.UIAxesTheta4dot = uiaxes(app.Theta4Tab);
            title(app.UIAxesTheta4dot, {'theta4 dot,'; 'Jacobian Method'})
            zlabel(app.UIAxesTheta4dot, 'Z')
            app.UIAxesTheta4dot_2 = uiaxes(app.Theta4Tab);
            title(app.UIAxesTheta4dot_2, {'theta4 dot'; 'Numberic Method'})
            zlabel(app.UIAxesTheta4dot_2, 'Z')
            app.TabGroup3_3 = uitabgroup(app.UIFigure);
            app.Theta3Tab = uitab(app.TabGroup3_3);
            app.Theta3Tab.Title = 'Theta3';
            app.UIAxesTheta3 = uiaxes(app.Theta3Tab);
            title(app.UIAxesTheta3, 'theta3 ')
            zlabel(app.UIAxesTheta3, 'Z')
            app.UIAxesTheta3dot = uiaxes(app.Theta3Tab);
            title(app.UIAxesTheta3dot, {'theta3 dot'; 'Jacobian Method'})
            zlabel(app.UIAxesTheta3dot, 'Z')
            app.UIAxesTheta3dot_2 = uiaxes(app.Theta3Tab);
            title(app.UIAxesTheta3dot_2, {'theta3 dot'; 'Numberic Method'})
            zlabel(app.UIAxesTheta3dot_2, 'Z')
            app.TabGroup3_2 = uitabgroup(app.UIFigure);
            app.Theta2Tab = uitab(app.TabGroup3_2);
            app.Theta2Tab.Title = 'Theta2';
            app.UIAxesTheta2 = uiaxes(app.Theta2Tab);
            title(app.UIAxesTheta2, 'theta2')
            zlabel(app.UIAxesTheta2, 'Z')
            app.UIAxesTheta2dot = uiaxes(app.Theta2Tab);
            title(app.UIAxesTheta2dot, {'theta2 dot'; 'Jacobian Method'})
            zlabel(app.UIAxesTheta2dot, 'Z')
            app.UIAxesTheta2dot_2 = uiaxes(app.Theta2Tab);
            title(app.UIAxesTheta2dot_2, {'theta2 dot'; 'Numberic Method'})
            zlabel(app.UIAxesTheta2dot_2, 'Z')
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.Theta1Tab = uitab(app.TabGroup3);
            app.Theta1Tab.Title = 'Theta1';
            app.UIAxesTheta1 = uiaxes(app.Theta1Tab);
            title(app.UIAxesTheta1, 'theta1')
            zlabel(app.UIAxesTheta1, 'Z')
            app.UIAxesTheta1dot = uiaxes(app.Theta1Tab);
            title(app.UIAxesTheta1dot, {'theta1 dot'; 'Jacobian Method'})
            zlabel(app.UIAxesTheta1dot, 'Z')
            app.UIAxesTheta1dot_2 = uiaxes(app.Theta1Tab);
            title(app.UIAxesTheta1dot_2, {'theta1 dot '; 'Numberic Method'})
            zlabel(app.UIAxesTheta1dot_2, 'Z')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.inversekinematicsTab = uitab(app.TabGroup2);
            app.inversekinematicsTab.Title = 'inverse kinematics';
            app.XEditFieldLabel = uilabel(app.inversekinematicsTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.inversekinematicsTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.inversekinematicsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.inversekinematicsTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.inversekinematicsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.inversekinematicsTab, 'numeric');
            app.pitchEditFieldLabel = uilabel(app.inversekinematicsTab);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.inversekinematicsTab, 'numeric');
            app.GotoButton = uibutton(app.inversekinematicsTab, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Goto';
            app.PathPlanningTab = uitab(app.TabGroup2);
            app.PathPlanningTab.Title = 'Path Planning';
            app.V_maxEditFieldLabel = uilabel(app.PathPlanningTab);
            app.V_maxEditFieldLabel.Text = 'V_max';
            app.V_maxEditField = uieditfield(app.PathPlanningTab, 'numeric');
            app.a_maxEditFieldLabel = uilabel(app.PathPlanningTab);
            app.a_maxEditFieldLabel.Text = 'a_max';
            app.a_maxEditField = uieditfield(app.PathPlanningTab, 'numeric');
            app.ModeDropDown_2 = uidropdown(app.PathPlanningTab);
            app.ModeDropDown_2.Items = {'Linear', 'Circular', 'SINGULARITIES'};
            app.ModeDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @ModeDropDown_2Opening, true);
            app.ModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_2ValueChanged, true);
            app.ModeDropDown_2.Value = 'SINGULARITIES';
            app.ModeDropDown_2Label = uilabel(app.PathPlanningTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.StartButton = uibutton(app.PathPlanningTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.APanel = uipanel(app.PathPlanningTab);
            app.APanel.Title = 'A';
            app.XEditField_2Label = uilabel(app.APanel);
            app.XEditField_2Label.Text = 'X';
            app.XAEditField = uieditfield(app.APanel, 'numeric');
            app.YEditField_2Label = uilabel(app.APanel);
            app.YEditField_2Label.Text = 'Y';
            app.YAEditField = uieditfield(app.APanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.APanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZAEditField = uieditfield(app.APanel, 'numeric');
            app.pitchEditField_2Label = uilabel(app.APanel);
            app.pitchEditField_2Label.Text = 'pitch';
            app.pitchAEditField = uieditfield(app.APanel, 'numeric');
            app.BPanel = uipanel(app.PathPlanningTab);
            app.BPanel.Title = 'B';
            app.XEditField_3Label = uilabel(app.BPanel);
            app.XEditField_3Label.Text = 'X';
            app.XBEditField = uieditfield(app.BPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.BPanel);
            app.YEditField_3Label.Text = 'Y';
            app.YBEditField = uieditfield(app.BPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.BPanel);
            app.ZEditField_3Label.Text = 'Z';
            app.ZBEditField = uieditfield(app.BPanel, 'numeric');
            app.pitchEditField_3Label = uilabel(app.BPanel);
            app.pitchEditField_3Label.Text = 'pitch';
            app.pitchBEditField = uieditfield(app.BPanel, 'numeric');
            app.CPanel = uipanel(app.PathPlanningTab);
            app.CPanel.Title = 'C';
            app.XEditField_4Label = uilabel(app.CPanel);
            app.XEditField_4Label.Text = 'X';
            app.XCEditField = uieditfield(app.CPanel, 'numeric');
            app.YEditField_4Label = uilabel(app.CPanel);
            app.YEditField_4Label.Text = 'Y';
            app.YCEditField = uieditfield(app.CPanel, 'numeric');
            app.ZEditField_4Label = uilabel(app.CPanel);
            app.ZEditField_4Label.Text = 'Z';
            app.ZCEditField = uieditfield(app.CPanel, 'numeric');
            app.pitchEditField_4Label = uilabel(app.CPanel);
            app.pitchEditField_4Label.Text = 'pitch';
            app.pitchCEditField = uieditfield(app.CPanel, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '0';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = '0';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = '0';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = '0';
            app.ShowAxisCheckBox = uicheckbox(app.UIFigure);
            app.ShowAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAxisCheckBoxValueChanged, true);
            app.ShowAxisCheckBox.Text = 'Show Axis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EndEffectorTab = uitab(app.TabGroup);
            app.EndEffectorTab.Title = 'End Effector ';
            app.PxEditFieldLabel = uilabel(app.EndEffectorTab);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.EndEffectorTab, 'text');
            app.PyEditFieldLabel = uilabel(app.EndEffectorTab);
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.EndEffectorTab, 'text');
            app.PzEditFieldLabel = uilabel(app.EndEffectorTab);
            app.PzEditFieldLabel.Text = 'Pz';
            app.PzEditField = uieditfield(app.EndEffectorTab, 'text');
            app.RollEditFieldLabel = uilabel(app.EndEffectorTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.EndEffectorTab, 'text');
            app.PitchEditFieldLabel = uilabel(app.EndEffectorTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.EndEffectorTab, 'text');
            app.YawEditFieldLabel = uilabel(app.EndEffectorTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.EndEffectorTab, 'text');
            app.DrawWorkSpaceButton = uibutton(app.UIFigure, 'push');
            app.DrawWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @DrawWorkSpaceButtonPushed, true);
            app.DrawWorkSpaceButton.Text = 'Draw WorkSpace';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta1Slider.MinorTicks = [];
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-90 90];
            app.Theta2Slider.MajorTicks = [];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta2Slider.MinorTicks = [];
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-90 90];
            app.Theta3Slider.MajorTicks = [];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta3Slider.MinorTicks = [];
            app.Theta4SliderLabel = uilabel(app.UIFigure);
            app.Theta4SliderLabel.Text = 'Theta4';
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.Limits = [-90 90];
            app.Theta4Slider.MajorTicks = [];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta4Slider.MinorTicks = [];
            app.OpacitySliderLabel = uilabel(app.UIFigure);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.UIFigure);
            app.OpacitySlider.Limits = [0 1];
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacitySliderValueChanged, true);
            app.OpacitySlider.ValueChangingFcn = createCallbackFcn(app, @OpacitySliderValueChanging, true);
            app.OpacitySlider.Value = 1;
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'forward kinematics', 'inverse kinematics', 'Path Planing'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'forward kinematics';
            app.TabGroup5 = uitabgroup(app.UIFigure);
            app.MotionprofileTab = uitab(app.TabGroup5);
            app.MotionprofileTab.Title = 'Motion profile';
            app.UIAxesA = uiaxes(app.MotionprofileTab);
            title(app.UIAxesA, 'a(t)')
            zlabel(app.UIAxesA, 'Z')
            app.UIAxesV = uiaxes(app.MotionprofileTab);
            title(app.UIAxesV, 'v(t)')
            zlabel(app.UIAxesV, 'Z')
            app.UIAxesP = uiaxes(app.MotionprofileTab);
            title(app.UIAxesP, 'p(t)')
            zlabel(app.UIAxesP, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end